classdef Circuit_analysis_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        CalculatorTab                   matlab.ui.container.Tab
        infoButton                      matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        PARALLELRLCLabel                matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        NaturalOutputsTab               matlab.ui.container.Tab
        EquationLabel_2                 matlab.ui.control.Label
        critic_naturel                  matlab.ui.control.Image
        under_naturel                   matlab.ui.control.Image
        over_naturel                    matlab.ui.control.Image
        Co_label_2                      matlab.ui.control.Label
        Damped_label_2                  matlab.ui.control.Label
        Co_label                        matlab.ui.control.Label
        CoefficentsLabel                matlab.ui.control.Label
        Damped_label                    matlab.ui.control.Label
        DampedRadianFrequencyLabel      matlab.ui.control.Label
        Resonant_label                  matlab.ui.control.Label
        Neper_label                     matlab.ui.control.Label
        ResonantRadianFrequencyLabel    matlab.ui.control.Label
        NeperFrequencyLabel             matlab.ui.control.Label
        S2_label                        matlab.ui.control.Label
        S1_label                        matlab.ui.control.Label
        SecondrootS2Label               matlab.ui.control.Label
        FirstrootS1Label                matlab.ui.control.Label
        StepOutputsTab                  matlab.ui.container.Tab
        EquationLabel                   matlab.ui.control.Label
        over_seri                       matlab.ui.control.Image
        critic_paralel                  matlab.ui.control.Image
        under_paralel                   matlab.ui.control.Image
        over_paralel                    matlab.ui.control.Image
        critic_seri                     matlab.ui.control.Image
        under_seri                      matlab.ui.control.Image
        Co_label_4                      matlab.ui.control.Label
        Damped_label_4                  matlab.ui.control.Label
        Co_label_3                      matlab.ui.control.Label
        CoefficentsLabel_2              matlab.ui.control.Label
        Damped_label_3                  matlab.ui.control.Label
        DampedRadianFrequencyLabel_2    matlab.ui.control.Label
        Resonant_label_2                matlab.ui.control.Label
        Neper_label_2                   matlab.ui.control.Label
        ResonantRadianFrequencyLabel_2  matlab.ui.control.Label
        NeperFrequencyLabel_2           matlab.ui.control.Label
        S2_label_2                      matlab.ui.control.Label
        S1_label_2                      matlab.ui.control.Label
        SecondrootS2Label_2             matlab.ui.control.Label
        FirstrootS1Label_2              matlab.ui.control.Label
        NaturalGraphTab                 matlab.ui.container.Tab
        Label6                          matlab.ui.control.Label
        MINLabel_2                      matlab.ui.control.Label
        Label5                          matlab.ui.control.Label
        MAXLabel_2                      matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        StepGraphTab                    matlab.ui.container.Tab
        Label4                          matlab.ui.control.Label
        MINLabel                        matlab.ui.control.Label
        Label3                          matlab.ui.control.Label
        MAXLabel                        matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        SERIESRLCLabel                  matlab.ui.control.Label
        Image_2                         matlab.ui.control.Image
        CALCULATEButton                 matlab.ui.control.Button
        CircuitTypeButtonGroup          matlab.ui.container.ButtonGroup
        ParallelButton                  matlab.ui.control.RadioButton
        SeriesButton                    matlab.ui.control.RadioButton
        Image                           matlab.ui.control.Image
        Panel                           matlab.ui.container.Panel
        RLCCALCULATORLabel              matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        INPUTSPanel                     matlab.ui.container.Panel
        time_edit                       matlab.ui.control.NumericEditField
        TimeLabel                       matlab.ui.control.Label
        INC_edit                        matlab.ui.control.NumericEditField
        INILabel                        matlab.ui.control.Label
        INV_edit                        matlab.ui.control.NumericEditField
        INVLabel                        matlab.ui.control.Label
        CS_edit                         matlab.ui.control.NumericEditField
        CurrentSourceLabel              matlab.ui.control.Label
        VS_edit                         matlab.ui.control.NumericEditField
        VoltageSourceLabel              matlab.ui.control.Label
        resistor_edit                   matlab.ui.control.NumericEditField
        ResistorRLabel                  matlab.ui.control.Label
        inductor_edit                   matlab.ui.control.NumericEditField
        InductorLLabel                  matlab.ui.control.Label
        farad_edit                      matlab.ui.control.NumericEditField
        CapacitorCLabel                 matlab.ui.control.Label
        INC_unit                        matlab.ui.control.DropDown
        INV_unit                        matlab.ui.control.DropDown
        CS_unit                         matlab.ui.control.DropDown
        VS_unit                         matlab.ui.control.DropDown
        resistr_unit                    matlab.ui.control.DropDown
        inductorunit                    matlab.ui.control.DropDown
        faradunit                       matlab.ui.control.DropDown
        time_unit                       matlab.ui.control.DropDown
        HelpTab                         matlab.ui.container.Tab
        TextArea_7                      matlab.ui.control.TextArea
        TextArea_6                      matlab.ui.control.TextArea
        TextArea_5                      matlab.ui.control.TextArea
        TextArea_4                      matlab.ui.control.TextArea
        TextArea_3                      matlab.ui.control.TextArea
        TextArea_2                      matlab.ui.control.TextArea
        TextArea                        matlab.ui.control.TextArea
        Image3                          matlab.ui.control.Image
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CALCULATEButton
        function CALCULATEButtonPushed(app, event)
            %% input degerlerini aliyoruz
            F_deger= app.farad_edit.Value;
            L_deger=app.inductor_edit.Value;
            R_deger=app.resistor_edit.Value;
            V_deger=app.VS_edit.Value;
            CUR_deger=app.CS_edit.Value;
            INV_deger=app.INV_edit.Value;
            INC_deger=app.INC_edit.Value;
            timeson=app.time_edit.Value;
            plot_step=[];
            plot_natural=[];
            %% kod hata vermemesi icin deger atiyoruz
            damped="NULL";
            if F_deger==0 || R_deger==0 || L_deger==0
                F_deger=1;
                R_deger=1;
                L_deger=1;
                msgbox("Enter the capacitor, inductor and resistor values other than zero.","Warning","warn")
            end
       

           %% birim donusumleri 
            x=app.faradunit.Value;
            switch x
                case 'mF'
                    F_deger=F_deger*0.001;
                case'μF'
                    F_deger=F_deger*0.000001;
                case'nF'
                    F_deger=F_deger*0.000000001;
                case'pF'
                    F_deger=F_deger*0.000000000001;
            end

            y = app.inductorunit.Value;
            switch y

                case'mH'
                    L_deger=L_deger*0.001;
                case'μH'
                    L_deger=L_deger*0.000001;
                case'nH'
                    L_deger=L_deger*0.000000001;
                case'pH'
                    L_deger=L_deger*0.000000000001;
            end

            z=app.resistr_unit.Value;
            switch z
                case'kOhm'
                    R_deger=R_deger*1000;
                case 'MOhm '
                    R_deger=R_deger*100000;
            end

            xy=app.VS_unit.Value;
            switch xy
                case 'mV'
                    V_deger=V_deger*0.001;
                case 'kV'
                    V_deger=V_deger*1000;
            end

            xz=app.CS_unit.Value;
            switch xz
                case 'mA'
                    CUR_deger=CUR_deger*0.001;

            end
            yx= app.INV_unit.Value;
            switch yx
                case 'mV'
                    INV_deger=INV_deger*0.001;

                case 'kV'
                    INV_deger=INV_deger*1000;
            end

            yz= app.INC_unit.Value;
            switch yz
                case 'mA'
                    INC_deger=INC_deger*0.001;
            end
            xyz=app.time_unit.Value;

            switch xyz
                case 'ms'
                    timeson= timeson*0.001;
                case'μs'
                    timeson=timeson*0.000001;

            end

            e=exp(1);
            t=0:(0.00001)*timeson:timeson;
            plot_step=[];
            plot_natural=[];
            durum= app.CircuitTypeButtonGroup.SelectedObject;

             %% seri devre hesaplamalari

            if durum== app.SeriesButton
                %% seri devreler icin ortak hesaplamalar
                VL_deger= -(INV_deger+(INC_deger*R_deger));
                turev_deger=VL_deger/L_deger;
                neper = R_deger/(2*L_deger);
                radian = 1/(L_deger*F_deger)^(1/2);
                s1_deger= (-(neper)+(((neper^2)-(radian^2)))^(1/2)) ;
                s2_deger= (-(neper)-(sqrt((neper^2)-(radian^2)))) ;
                WD= sqrt((radian)^2-(neper)^2);

                %% overdamped seri hesaplamalar
                if neper^2>radian^2
                    damped="Overdamped";
                    %% natural overdamped
                    degerler=[1 1 ; s1_deger s2_deger];
                    degerler2=[INC_deger;turev_deger];
                    cof1=linsolve(degerler,degerler2);
                    app.Co_label.Text=num2str(cof1(1));
                    app.Co_label_2.Text=num2str(cof1(2));

                    %%  step overdamped
                    INC_deger1=INC_deger-V_deger;
                    turev_deger1=turev_deger-V_deger;
                    degerler3=[INC_deger1 ; turev_deger1];

                    cof2=linsolve(degerler,degerler3);
                    app.Co_label_3.Text=num2str(cof2(1));
                    app.Co_label_4.Text=num2str(cof2(2));
                    
                    app.over_seri.Visible="on";
                    app.over_naturel.Visible="on";
                    app.over_paralel.Visible="off";
                    app.under_seri.Visible="off";
                    app.under_naturel.Visible="off";
                    app.under_paralel.Visible="off";
                    app.critic_paralel.Visible="off";
                    app.critic_seri.Visible="off";
                    app.critic_naturel.Visible="off";


                    [assa,time_matrix ]=size(t);
                    for i=1:time_matrix
                        time=t(i);
                        plot_step(i)=(cof2(1)*exp(s1_deger*time))+(cof2(2) *exp(s2_deger*time))+V_deger;
                        plot_natural(i)=(cof1(1)*exp(s1_deger*time))+(cof1(2) *exp(s2_deger*time));
                    end


                    %% seri underdamp
                elseif neper^2<radian^2
                    damped="Underdamped";
                    %% naturel underdamped
                    neper1=-neper;
                    degerler=[1 0; neper1 WD];
                    degerler2=[INC_deger;turev_deger];
                    cof1=linsolve(degerler,degerler2);

                    app.Co_label.Text=num2str(cof1(1));
                    app.Co_label_2.Text=num2str(cof1(2));


                    %%  step underdamped 
                    INC_deger=INC_deger-V_deger;
                    turev_deger=turev_deger-V_deger;
                    degerler3=[INC_deger ; turev_deger];

                    cof2=linsolve(degerler,degerler3);
                    app.Co_label_3.Text=num2str(cof2(1));
                    app.Co_label_4.Text=num2str(cof2(2));

                    app.over_seri.Visible="off";
                    app.over_naturel.Visible="off";
                    app.over_paralel.Visible="off";
                    app.under_seri.Visible="on";
                    app.under_naturel.Visible="on";
                    app.under_paralel.Visible="off";
                    app.critic_paralel.Visible="off";
                    app.critic_seri.Visible="off";
                    app.critic_naturel.Visible="off";


                    [sasa,time_matrix ]=size(t);
                    for i=1:time_matrix
                        time=t(i);
                        plot_natural(i)=(cof1(1)*cos(WD*time)*exp(-neper*time))+(cof1(2)*sin(WD*time)*exp(-neper*time));
                        plot_step(i)=(cof2(1)*cos(WD*time)*exp(-neper*time))+(cof2(2)*sin(WD*time)*exp(-neper*time))+V_deger;

                    end

                 
                    %% seri critically 

                elseif neper^2 == radian^2
                    damped= "Criticallydamped";
                    %% natural critically
                    neper1=-neper;
                    degerler=[0 1 ; 1 neper1];
                    degerler2=[INC_deger;turev_deger];
                    cof1=linsolve(degerler,degerler2);

                    app.Co_label.Text=num2str(cof1(1));
                    app.Co_label_2.Text=num2str(cof1(2));
                    %  plot_natural=cof1(1)*t*e.^(-neper*t)+cof1(2)*e.^(-neper*t);


                    %%  step critically
                    INC_deger=INC_deger-(V_deger/R_deger);
                    turev_deger=turev_deger-V_deger;
                    degerler3=[INC_deger ; turev_deger];

                    cof2=linsolve(degerler,degerler3);
                    app.Co_label_3.Text=num2str(cof2(1));
                    app.Co_label_4.Text=num2str(cof2(2));
                    [assa,time_matrix ]=size(t);
                    app.over_seri.Visible="off";
                    app.over_naturel.Visible="off";
                    app.over_paralel.Visible="off";
                    app.under_seri.Visible="off";
                    app.under_naturel.Visible="off";
                    app.under_paralel.Visible="off";
                    app.critic_paralel.Visible="off";
                    app.critic_seri.Visible="on";
                    app.critic_naturel.Visible="on";

                    for i=1:time_matrix
                        time=t(i);
                        plot_natural(i)=(cof1(1)*time*exp(-neper*time))+(cof1(2)*exp(-neper*time));
                        plot_step(i)=(cof2(1)*time*exp(-neper*time))+(cof2(2)*exp(-neper*time))+V_deger;
                    end
                end

                %%    series naturel response
                app.S1_label.Text=num2str(s1_deger );
                app.S2_label.Text=num2str(s2_deger);
                app.Neper_label.Text=num2str(neper);
                app.Resonant_label.Text=num2str(radian);
                app.Damped_label.Text=damped;
                app.Damped_label_2.Text=num2str(WD);


                %%      series step response
                app.S1_label_2.Text=num2str(s1_deger );
                app.S2_label_2.Text=num2str(s2_deger);
                app.Neper_label_2.Text=num2str(neper);
                app.Resonant_label_2.Text=num2str(radian);
                app.Damped_label_3.Text=damped;
                app.Damped_label_4.Text=num2str(WD);

                plot(app.UIAxes,t,plot_natural)
                plot(app.UIAxes2,t,plot_step)


                %% paralel devre hesaplamalari 
            elseif durum == app.ParallelButton

                turev_deger2=  -(INV_deger+R_deger*INC_deger)/(F_deger*R_deger);
                neper = 1/(2*F_deger*R_deger);
                radian = 1/sqrt(L_deger*F_deger);
                s1_deger= -(neper)+sqrt((neper^2)-(radian^2));
                s2_deger= -(neper)-sqrt((neper^2)-(radian^2));
                WD= sqrt((radian)^2-(neper)^2);

                %% paralel overdamped 
                if neper^2>radian^2
                    damped="Overdamped";

                    %% natural overdamped
                    degerler=[1 1 ; s1_deger s2_deger];
                    degerler2=[INV_deger;turev_deger2];
                    cof1=linsolve(degerler,degerler2);

                    app.Co_label.Text=num2str(cof1(1));
                    app.Co_label_2.Text=num2str(cof1(2));


                    %%  step overdamped
                    INV_deger=INV_deger-CUR_deger;
                    turev_deger2=turev_deger2-CUR_deger;
                    degerler3=[INV_deger ; turev_deger2];

                    cof2=linsolve(degerler,degerler3);
                    app.Co_label_3.Text=num2str(cof2(1));
                    app.Co_label_4.Text=num2str(cof2(2));
                    app.over_seri.Visible="off";
                    app.over_naturel.Visible="on";
                    app.over_paralel.Visible="on";
                    app.under_seri.Visible="off";
                    app.under_naturel.Visible="off";
                    app.under_paralel.Visible="off";
                    app.critic_paralel.Visible="off";
                    app.critic_seri.Visible="off";
                    app.critic_naturel.Visible="off";
                    [sasa, time_matrix ]=size(t);

                    for i=1:time_matrix
                        time=t(i);
                        plot_natural(i)=(cof1(1)*exp(s1_deger*time))+(cof1(2) *exp(s2_deger*time));
                        plot_step(i)=(cof2(1)*exp(s1_deger*time))+(cof2(2) *exp(s2_deger*time))+CUR_deger;
                    end


                    %% parallel underdamped
                elseif neper^2<radian^2
                    damped="Underdamped";
                    %% natural underdamped 
                    neper1=-neper;
                    degerler=[1 0; neper1 WD];
                    degerler2=[INV_deger;turev_deger2];
                    cof1=linsolve(degerler,degerler2);

                    app.Co_label.Text=num2str(cof1(1));
                    app.Co_label_2.Text=num2str(cof1(2));


                    %%  step underdamped
                    INV_deger=INV_deger-CUR_deger;
                    turev_deger2=turev_deger2-CUR_deger;
                    degerler3=[INV_deger ; turev_deger2];

                    cof2=linsolve(degerler,degerler3);
                    app.Co_label_3.Text=num2str(cof2(1));
                    app.Co_label_4.Text=num2str(cof2(2));

                    app.over_seri.Visible="off";
                    app.over_naturel.Visible="off";
                    app.over_paralel.Visible="off";
                    app.under_seri.Visible="off";
                    app.under_naturel.Visible="on";
                    app.under_paralel.Visible="on";
                    app.critic_paralel.Visible="off";
                    app.critic_seri.Visible="off";
                    app.critic_naturel.Visible="off";
                    [sasa, time_matrix ]=size(t);

                    for i=1:time_matrix
                        time=t(i);
                        plot_natural(i)=(cof1(1)*cos(WD*time)*exp(-neper*time))+(cof1(2)*sin(WD*time)*exp(-neper*time));
                        plot_step(i)=(cof2(1)*cos(WD*time)*exp(-neper*time))+(cof2(2)*sin(WD*time)*exp(-neper*time))+CUR_deger;
                    end


                    %% parallel critically 
                elseif neper^2 == radian^2
                    damped= "Criticallydamped";
                    %% natural critically 
                    neper1=-neper;
                    degerler=[0 1 ; 1 neper1];
                    degerler2=[INV_deger;turev_deger2];
                    cof1=linsolve(degerler,degerler2);

                    app.Co_label.Text=num2str(cof1(1));
                    app.Co_label_2.Text=num2str(cof1(2));

                    app.over_seri.Visible="off";
                    app.over_naturel.Visible="off";
                    app.over_paralel.Visible="off";
                    app.under_seri.Visible="off";
                    app.under_naturel.Visible="off";
                    app.under_paralel.Visible="off";
                    app.critic_paralel.Visible="on";
                    app.critic_seri.Visible="off";
                    app.critic_naturel.Visible="on";


                    %%  step critically 
                    INV_deger=INV_deger-CUR_deger;
                    turev_deger2=turev_deger2-CUR_deger;
                    degerler3=[INV_deger ; turev_deger2];

                    cof2=linsolve(degerler,degerler3);
                    app.Co_label_3.Text=num2str(cof2(1));
                    app.Co_label_4.Text=num2str(cof2(2));
                    [sassa , time_matrix]=size(t);

                    for i=1:time_matrix
                        time=t(i);
                        plot_natural(i)=(cof1(1)*time*exp(-neper*time))+(cof1(2)*exp(-neper*time));
                        plot_step(i)=(cof2(1)*time*exp(-neper*time))+(cof2(2)*exp(-neper*time))+CUR_deger;
                    end

                end
                %%  parallel natural
                app.S1_label.Text=num2str(s1_deger );
                app.S2_label.Text=num2str(s2_deger);
                app.Neper_label.Text=num2str(neper);
                app.Resonant_label.Text=num2str(radian);
                app.Damped_label.Text=damped;
                app.Damped_label_2.Text=num2str(WD);

                %%      parallel step
                app.S1_label_2.Text=num2str(s1_deger );
                app.S2_label_2.Text=num2str(s2_deger);
                app.Neper_label_2.Text=num2str(neper);
                app.Resonant_label_2.Text=num2str(radian);
                app.Damped_label_3.Text=damped;
                app.Damped_label_4.Text=num2str(WD);

                plot(app.UIAxes,t,plot_natural);
                plot(app.UIAxes2,t,plot_step)

            end
                max_value_step= max(plot_step);
                min_value_step = min(plot_step);
                app.Label3.Text = num2str(max_value_step);
                app.Label4.Text=num2str(min_value_step);
                max_value_natural= max(plot_natural);
                min_value_naturel = min(plot_natural);
                app.Label5.Text = num2str(max_value_natural);
                app.Label6.Text=num2str(min_value_naturel);

        end

        % Selection changed function: CircuitTypeButtonGroup
        function CircuitTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.CircuitTypeButtonGroup.SelectedObject;


            %% seri 
            if selectedButton== app.SeriesButton
                app.Image.Visible="off";
                app.Image_2.Visible="on";
                app.CS_unit.Enable="off";
                app.CS_edit.Enable="off";
                app.VS_edit.Enable="on";
                app.VS_unit.Enable="on";
                app.PARALLELRLCLabel.Visible="off";
                app.SERIESRLCLabel.Visible="on";
                app.UIAxes.YLabel.String="Voltage";
                app.UIAxes2.YLabel.String="Voltage";


                %% parallel
            elseif selectedButton== app.ParallelButton
                app.Image.Visible="on";
                app.Image_2.Visible="off";
                app.CS_unit.Enable="on";
                app.CS_edit.Enable="on";
                app.VS_edit.Enable="off";
                app.VS_unit.Enable="off";
                app.PARALLELRLCLabel.Visible="on";
                app.SERIESRLCLabel.Visible="off";
                app.UIAxes.YLabel.String="Current";
                app.UIAxes2.YLabel.String="Current";

            end
        end

        % Clicked callback: faradunit
        function faradunitClicked(app, event)

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.farad_edit.Value=0;
            app.inductor_edit.Value=0;
            app.resistor_edit.Value=0;
            app.VS_edit.Value=0;
            app.CS_edit.Value=0;
            app.INC_edit.Value=0;
            app.INV_edit.Value=0;
            app.time_edit.Value=0;
            app.S1_label.Text = "NULL";
            app.S2_label.Text = "NULL";
            app.Neper_label.Text = "NULL";
            app.Resonant_label.Text = "NULL";
            app.Damped_label.Text ="NULL";
            app.Damped_label_2.Text ="NULL";
            app.Co_label.Text = "NULL";
            app.Co_label_2.Text = "NULL";
            app.Label3.Text = "NULL";
            app.Label4.Text = "NULL";
            app.Label5.Text ="NULL";
            app.Label6.Text = "NULL";
            

            app.S1_label_2.Text = "NULL";
            app.S2_label_2.Text = "NULL";
            app.Neper_label_2.Text = "NULL";
            app.Resonant_label_2.Text = "NULL";
            app.Damped_label_3.Text ="NULL";
            app.Damped_label_4.Text ="NULL";
            app.Co_label_3.Text = "NULL";
            app.Co_label_4.Text = "NULL";

            app.over_seri.Visible="off";
            app.over_naturel.Visible="off";
            app.over_paralel.Visible="off";
            app.under_seri.Visible="off";
            app.under_naturel.Visible="off";
            app.under_paralel.Visible="off";
            app.critic_paralel.Visible="off";
            app.critic_seri.Visible="off";
            app.critic_naturel.Visible="off";


        end

        % Button pushed function: infoButton
        function infoButtonPushed(app, event)
            msgbox("Welcome to our RLC Calculator. You can calculate various RLC equation through this app. In order to make a correct calculation, you must enter all the input values in the inputs section. Otherwise, you may not get the correct result. After entering the inputs, you can compare the data you want from the output section by pressing the Calculate button. You can select Serial or Parallel RLC circuits from the radio button section above the info button and make your calculations according to different types. If you have a source in your circuit, do not forget to take into account the calculations in the step section, if not, in the natural section! Whatever you want to see in the Output section, simply touch that title. For more information, you can click on the Help tab at the top left. ","Information","help")
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.CalculatorTab = uitab(app.TabGroup);
            app.CalculatorTab.Title = 'Calculator';
            app.INPUTSPanel = uipanel(app.CalculatorTab);
            app.INPUTSPanel.Title = 'INPUTS';
            app.time_unit = uidropdown(app.INPUTSPanel);
            app.time_unit.Items = {'s', 'ms', 'μs'};
            app.time_unit.Value = 's';
            app.faradunit = uidropdown(app.INPUTSPanel);
            app.faradunit.Items = {'F', 'mF', 'μF', 'nF', 'pF'};
            app.faradunit.ClickedFcn = createCallbackFcn(app, @faradunitClicked, true);
            app.faradunit.Value = 'F';
            app.inductorunit = uidropdown(app.INPUTSPanel);
            app.inductorunit.Items = {'H', 'mH', 'μH', 'nH', 'pH'};
            app.inductorunit.Value = 'H';
            app.resistr_unit = uidropdown(app.INPUTSPanel);
            app.resistr_unit.Items = {'Ohm', 'kOhm', 'MOhm'};
            app.resistr_unit.Value = 'Ohm';
            app.VS_unit = uidropdown(app.INPUTSPanel);
            app.VS_unit.Items = {'mV', 'V', 'kV'};
            app.VS_unit.Value = 'V';
            app.CS_unit = uidropdown(app.INPUTSPanel);
            app.CS_unit.Items = {'mA', 'A'};
            app.CS_unit.Enable = 'off';
            app.CS_unit.Value = 'A';
            app.INV_unit = uidropdown(app.INPUTSPanel);
            app.INV_unit.Items = {'mV', 'V', 'kV'};
            app.INV_unit.Value = 'V';
            app.INC_unit = uidropdown(app.INPUTSPanel);
            app.INC_unit.Items = {'mA', 'A'};
            app.INC_unit.Value = 'A';
            app.CapacitorCLabel = uilabel(app.INPUTSPanel);
            app.CapacitorCLabel.Text = 'Capacitor (C) :';
            app.farad_edit = uieditfield(app.INPUTSPanel, 'numeric');
            app.farad_edit.Tooltip = {''};
            app.InductorLLabel = uilabel(app.INPUTSPanel);
            app.InductorLLabel.Text = 'Inductor(L) :';
            app.inductor_edit = uieditfield(app.INPUTSPanel, 'numeric');
            app.ResistorRLabel = uilabel(app.INPUTSPanel);
            app.ResistorRLabel.Text = 'Resistor(R) :';
            app.resistor_edit = uieditfield(app.INPUTSPanel, 'numeric');
            app.VoltageSourceLabel = uilabel(app.INPUTSPanel);
            app.VoltageSourceLabel.Text = 'Voltage Source : ';
            app.VS_edit = uieditfield(app.INPUTSPanel, 'numeric');
            app.CurrentSourceLabel = uilabel(app.INPUTSPanel);
            app.CurrentSourceLabel.Text = 'Current Source :';
            app.CS_edit = uieditfield(app.INPUTSPanel, 'numeric');
            app.CS_edit.Enable = 'off';
            app.INVLabel = uilabel(app.INPUTSPanel);
            app.INVLabel.Text = 'IN(V):';
            app.INV_edit = uieditfield(app.INPUTSPanel, 'numeric');
            app.INILabel = uilabel(app.INPUTSPanel);
            app.INILabel.Text = 'IN(I):';
            app.INC_edit = uieditfield(app.INPUTSPanel, 'numeric');
            app.TimeLabel = uilabel(app.INPUTSPanel);
            app.TimeLabel.Text = 'Time: ';
            app.time_edit = uieditfield(app.INPUTSPanel, 'numeric');
            app.Panel = uipanel(app.CalculatorTab);
            app.Image2 = uiimage(app.Panel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'KhasLogo1.png');
            app.RLCCALCULATORLabel = uilabel(app.Panel);
            app.RLCCALCULATORLabel.Text = 'RLC CALCULATOR';
            app.Image = uiimage(app.CalculatorTab);
            app.Image.Visible = 'off';
            app.Image.ImageSource = fullfile(pathToMLAPP, 'paraalrel.jpg');
            app.CircuitTypeButtonGroup = uibuttongroup(app.CalculatorTab);
            app.CircuitTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CircuitTypeButtonGroupSelectionChanged, true);
            app.CircuitTypeButtonGroup.Title = 'Circuit Type';
            app.SeriesButton = uiradiobutton(app.CircuitTypeButtonGroup);
            app.SeriesButton.Text = 'Series';
            app.SeriesButton.Value = true;
            app.ParallelButton = uiradiobutton(app.CircuitTypeButtonGroup);
            app.ParallelButton.Text = 'Parallel';
            app.CALCULATEButton = uibutton(app.CalculatorTab, 'push');
            app.CALCULATEButton.ButtonPushedFcn = createCallbackFcn(app, @CALCULATEButtonPushed, true);
            app.CALCULATEButton.Text = 'CALCULATE';
            app.Image_2 = uiimage(app.CalculatorTab);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'esrsrs.jpg');
            app.SERIESRLCLabel = uilabel(app.CalculatorTab);
            app.SERIESRLCLabel.Text = 'SERIES RLC ';
            app.TabGroup2 = uitabgroup(app.CalculatorTab);
            app.NaturalOutputsTab = uitab(app.TabGroup2);
            app.NaturalOutputsTab.Title = 'Natural Outputs';
            app.FirstrootS1Label = uilabel(app.NaturalOutputsTab);
            app.FirstrootS1Label.Text = 'First root S1:';
            app.SecondrootS2Label = uilabel(app.NaturalOutputsTab);
            app.SecondrootS2Label.Text = 'Second root S2:';
            app.S1_label = uilabel(app.NaturalOutputsTab);
            app.S2_label = uilabel(app.NaturalOutputsTab);
            app.S2_label.Text = 'Label2';
            app.NeperFrequencyLabel = uilabel(app.NaturalOutputsTab);
            app.NeperFrequencyLabel.Text = 'Neper Frequency:';
            app.ResonantRadianFrequencyLabel = uilabel(app.NaturalOutputsTab);
            app.ResonantRadianFrequencyLabel.Text = 'Resonant Radian Frequency:';
            app.Neper_label = uilabel(app.NaturalOutputsTab);
            app.Resonant_label = uilabel(app.NaturalOutputsTab);
            app.Resonant_label.Text = 'Label2';
            app.DampedRadianFrequencyLabel = uilabel(app.NaturalOutputsTab);
            app.DampedRadianFrequencyLabel.Text = 'Damped Radian Frequency:';
            app.Damped_label = uilabel(app.NaturalOutputsTab);
            app.CoefficentsLabel = uilabel(app.NaturalOutputsTab);
            app.CoefficentsLabel.Text = 'Coefficents:';
            app.Co_label = uilabel(app.NaturalOutputsTab);
            app.Damped_label_2 = uilabel(app.NaturalOutputsTab);
            app.Co_label_2 = uilabel(app.NaturalOutputsTab);
            app.over_naturel = uiimage(app.NaturalOutputsTab);
            app.over_naturel.Visible = 'off';
            app.over_naturel.ImageSource = fullfile(pathToMLAPP, 'naturel_over.jpeg');
            app.under_naturel = uiimage(app.NaturalOutputsTab);
            app.under_naturel.Visible = 'off';
            app.under_naturel.ImageSource = fullfile(pathToMLAPP, 'naturel_under.jpeg');
            app.critic_naturel = uiimage(app.NaturalOutputsTab);
            app.critic_naturel.Visible = 'off';
            app.critic_naturel.ImageSource = fullfile(pathToMLAPP, 'naturel_critically.jpeg');
            app.EquationLabel_2 = uilabel(app.NaturalOutputsTab);
            app.EquationLabel_2.Text = 'Equation:';
            app.StepOutputsTab = uitab(app.TabGroup2);
            app.StepOutputsTab.Title = 'Step Outputs';
            app.FirstrootS1Label_2 = uilabel(app.StepOutputsTab);
            app.FirstrootS1Label_2.Text = 'First root S1:';
            app.SecondrootS2Label_2 = uilabel(app.StepOutputsTab);
            app.SecondrootS2Label_2.Text = 'Second root S2:';
            app.S1_label_2 = uilabel(app.StepOutputsTab);
            app.S2_label_2 = uilabel(app.StepOutputsTab);
            app.S2_label_2.Text = 'Label2';
            app.NeperFrequencyLabel_2 = uilabel(app.StepOutputsTab);
            app.NeperFrequencyLabel_2.Text = 'Neper Frequency:';
            app.ResonantRadianFrequencyLabel_2 = uilabel(app.StepOutputsTab);
            app.ResonantRadianFrequencyLabel_2.Text = 'Resonant Radian Frequency:';
            app.Neper_label_2 = uilabel(app.StepOutputsTab);
            app.Resonant_label_2 = uilabel(app.StepOutputsTab);
            app.Resonant_label_2.Text = 'Label2';
            app.DampedRadianFrequencyLabel_2 = uilabel(app.StepOutputsTab);
            app.DampedRadianFrequencyLabel_2.Text = 'Damped Radian Frequency:';
            app.Damped_label_3 = uilabel(app.StepOutputsTab);
            app.CoefficentsLabel_2 = uilabel(app.StepOutputsTab);
            app.CoefficentsLabel_2.Text = 'Coefficents:';
            app.Co_label_3 = uilabel(app.StepOutputsTab);
            app.Damped_label_4 = uilabel(app.StepOutputsTab);
            app.Co_label_4 = uilabel(app.StepOutputsTab);
            app.under_seri = uiimage(app.StepOutputsTab);
            app.under_seri.Visible = 'off';
            app.under_seri.ImageSource = fullfile(pathToMLAPP, 'seri_step_under.jpeg');
            app.critic_seri = uiimage(app.StepOutputsTab);
            app.critic_seri.Visible = 'off';
            app.critic_seri.ImageSource = fullfile(pathToMLAPP, 'seri_step_critically.jpeg');
            app.over_paralel = uiimage(app.StepOutputsTab);
            app.over_paralel.Visible = 'off';
            app.over_paralel.ImageSource = fullfile(pathToMLAPP, 'paralel_step_over.jpeg');
            app.under_paralel = uiimage(app.StepOutputsTab);
            app.under_paralel.Visible = 'off';
            app.under_paralel.ImageSource = fullfile(pathToMLAPP, 'paralel_step_under.jpeg');
            app.critic_paralel = uiimage(app.StepOutputsTab);
            app.critic_paralel.Visible = 'off';
            app.critic_paralel.ImageSource = fullfile(pathToMLAPP, 'paralel_step_critically.jpeg');
            app.over_seri = uiimage(app.StepOutputsTab);
            app.over_seri.Visible = 'off';
            app.over_seri.ImageSource = fullfile(pathToMLAPP, 'seri_step_over.jpeg');
            app.EquationLabel = uilabel(app.StepOutputsTab);
            app.EquationLabel.Text = 'Equation:';
            app.NaturalGraphTab = uitab(app.TabGroup2);
            app.NaturalGraphTab.Title = 'Natural Graph';
            app.UIAxes = uiaxes(app.NaturalGraphTab);
            title(app.UIAxes, 'NATURAL GRAPH')
            xlabel(app.UIAxes, 'TIME')
            ylabel(app.UIAxes, 'Voltage')
            zlabel(app.UIAxes, 'Z')
            app.MAXLabel_2 = uilabel(app.NaturalGraphTab);
            app.MAXLabel_2.Text = 'MAX :';
            app.Label5 = uilabel(app.NaturalGraphTab);
            app.Label5.Text = 'Label5';
            app.MINLabel_2 = uilabel(app.NaturalGraphTab);
            app.MINLabel_2.Text = 'MIN :';
            app.Label6 = uilabel(app.NaturalGraphTab);
            app.Label6.Text = 'Label6';
            app.StepGraphTab = uitab(app.TabGroup2);
            app.StepGraphTab.Title = 'Step Graph';
            app.UIAxes2 = uiaxes(app.StepGraphTab);
            title(app.UIAxes2, 'STEP GRAPH')
            xlabel(app.UIAxes2, 'TIME')
            ylabel(app.UIAxes2, 'Voltage')
            zlabel(app.UIAxes2, 'Z')
            app.MAXLabel = uilabel(app.StepGraphTab);
            app.MAXLabel.Text = 'MAX :';
            app.Label3 = uilabel(app.StepGraphTab);
            app.Label3.Text = 'Label3';
            app.MINLabel = uilabel(app.StepGraphTab);
            app.MINLabel.Text = 'MIN :';
            app.Label4 = uilabel(app.StepGraphTab);
            app.Label4.Text = 'Label4';
            app.PARALLELRLCLabel = uilabel(app.CalculatorTab);
            app.PARALLELRLCLabel.Visible = 'off';
            app.PARALLELRLCLabel.Text = 'PARALLEL RLC';
            app.ResetButton = uibutton(app.CalculatorTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.infoButton = uibutton(app.CalculatorTab, 'push');
            app.infoButton.ButtonPushedFcn = createCallbackFcn(app, @infoButtonPushed, true);
            app.infoButton.Text = 'info';
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.Image3 = uiimage(app.HelpTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'HelpImage.png');
            app.TextArea = uitextarea(app.HelpTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Part 1: This is the part where the input values you will use during the calculation are entered. In this section, capacitor, inductor and resistor values are entered. If it is a series connected circuit, the voltage source is entered, if it is a parallel connected circuit, the current source value is entered. Also, this is the field where the initial voltage (initial voltage on the capacitor), initial current (initial current on the inductor) and time input values should be entered. Here, you can make your calculations by entering the necessary inputs according to the values you want to calculate. In addition, you can enter the real numbers you want in this section.'};
            app.TextArea_2 = uitextarea(app.HelpTab);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'Part 2: This is the section where the units of your input values are set. Thanks to this section, it is possible to make easy calculations by easily switching between the units we have determined for each input value.'};
            app.TextArea_3 = uitextarea(app.HelpTab);
            app.TextArea_3.Value = {'Part 3: This is the part where the user determines whether the calculations will be made for a series RLC circuit or a parallel RLC circuit.'};
            app.TextArea_4 = uitextarea(app.HelpTab);
            app.TextArea_4.Value = {'Part 4: This is the section with sample circuits. Depending on your selection in part 3, the photos here also change, and an example circuit is shown for the user.'};
            app.TextArea_5 = uitextarea(app.HelpTab);
            app.TextArea_5.Value = {'Part 5: This is the section that calculates the values entered in the inputs section. You can easily calculate all the data displayed in the output section by clicking the Calculate button.'};
            app.TextArea_6 = uitextarea(app.HelpTab);
            app.TextArea_6.Value = {'Part 6: This is the section that makes all values equal to 0 by clicking the reset button after the calculations are made.'};
            app.TextArea_7 = uitextarea(app.HelpTab);
            app.TextArea_7.Value = {'Part 7: This is the section where output values are shown. This is 4 sections in itself. These are natural output, step output, natural graph and step graph. Here, the roots of the characteristic equations for the natural and step output titles, the neper and resonant radian frequency values, and the dumping value of the circuit are shown. Below are the formulas used in calculating the coefficients values and these coefficients values. A power supply value must be entered in order to receive step response outputs. Otherwise, step response values will not be correct. However, if you are calculating the step response, you can also see the natural response values correctly. In the section where V-T and I-T graphs are located, X and Y values of this point can be seen when clicking on any point in the function in the resulting graphs.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Circuit_analysis_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end